classdef Segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SegmentationAnalyzeUIFigure  matlab.ui.Figure
        LoadImageMenu                matlab.ui.container.Menu
        AnalyzeMenu                  matlab.ui.container.Menu
        Menu                         matlab.ui.container.Menu
        LoadImageButton              matlab.ui.control.Button
        FilePathEditFieldLabel       matlab.ui.control.Label
        FilePathEditField            matlab.ui.control.EditField
        AnalyzeImageButton           matlab.ui.control.Button
        PixelAreaEditFieldLabel      matlab.ui.control.Label
        PixelAreaEditField           matlab.ui.control.NumericEditField
        FilterThresholdLabel         matlab.ui.control.Label
        FilterThresholdSlider        matlab.ui.control.Slider
        AreastoExtractDropDownLabel  matlab.ui.control.Label
        AreastoExtractDropDown       matlab.ui.control.DropDown
        SetthevalueyouwantLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        AxesHistogram                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        I = [];                     % image file
        threshold = 120;            % threshold for binarizing gray intensity
        regionsToExtract = 2;       % amount of lungs you want to extract
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: LoadImageButton, LoadImageMenu
        function LoadImageButtonPushed(app, event)
            clc;
            
            Dir = 'D:\Study_University_Borys_Bilkevych_4_course_2_semestr\Diplom_work\Own realizations\Staging-COVID-19-Detection-System-using-Chest-X-Ray-Images-master\Analyze Segmentation (Part_1)'; %define invariate file "prefix"            
            [imageExt, path] =  uigetfile('*.png'); % take a variable part of image name
            imageName = [Dir filesep imageExt]; % concatenate the variables, make one path
            
            if isfile(imageName)                        % check if the file exist
                app.I = imread(imageName);              % read the image
            
                imshow(app.I,'parent',app.UIAxes);      % display the image in the GUI
              
                app.FilePathEditField.Value = path ;    % display file path of where original image came from
                app.AnalyzeImageButton.Enable=1;        % enable the button Analyse image inside the GUI
                app.AnalyzeMenu.Enable=1;               % enable the button Analyse image in the Menu bar
            else
                 f = msgbox('You didn`t choose the image', 'Error','error'); % enter the error message if the file doesn`t exist
            end
        end

        % Callback function: AnalyzeImageButton, AnalyzeMenu
        function AnalyzeImageButtonPushed(app, event)
            originalImage = wiener2(app.I, [5 5]);              % dot-removal filter
            imshow(originalImage,'parent',app.UIAxes2);
            
            histogram(app.AxesHistogram,app.I, 256);            % display histogram of image
            
            a_thresh = originalImage >= app.threshold;          % set this threshold. Boolean value          
            labelImage = bwlabel(a_thresh);                     % create a matrix
            props = regionprops(a_thresh,'all');                % measures a set of properties for each labeled region in the label matrix
            sortedSolidity = sort([props.Solidity], 'descend'); % sort the matrix by descending

            SB = sortedSolidity(1);                             % set the value of the first element
            
            if SB == 1                                          % SB only accept solidity == 1 filter out bones 
                SE  = strel('square',3);                        % set the type of figure and diametr
                morphologicalGradient = imsubtract(imdilate(labelImage, SE),imerode(labelImage, SE)); % define the ares of lungs. Only borders
                mask = imbinarize(morphologicalGradient,0.03); % repair the light in the morphologicalGradient
                                imshow(morphologicalGradient,'parent',app.UIAxes2);

                SE  = strel('square',2);                        % make more details
                mask = imclose(mask, SE);                       % perform morphological closing on the grayscale or binary image
                              
                mask = imfill(mask,'holes');                    % change the squares to holes
                mask = bwareafilt(mask,app.regionsToExtract);   % control number of area show. Delete all not important details from plot
                notMask = ~mask;                                % logical not operator
                mask = mask | bwpropfilt(notMask,'Area',[-Inf, 5000 - eps(5000)]); % One more condition for more concretical detecting of infected parts
                                
                BW2 = imfill(labelImage,'holes');               % create new image
                new_image = BW2 ;                               % re-assign the variable
                new_image(~mask) = 0;                           % select the part infected
                                
                B = bwboundaries(new_image);                    % traces the exterior boundaries of objects, as well as boundaries of holes inside these objects
            
                imshow(new_image,'parent',app.UIAxes2);
                hold(app.UIAxes2,'on');
                visboundaries(B);
                set(gca,'YDir','reverse');
               
                lungArea = bwarea(new_image);
                app.PixelAreaEditField.Value = lungArea;
            end
        end

        % Value changed function: FilterThresholdSlider
        function FilterThresholdSliderValueChanged(app, event)
            app.threshold = app.FilterThresholdSlider.Value;            % set the threshold value
            %AnalyzeImageButtonPushed(app, event);
        end

        % Callback function
        function AreastoExtractKnobValueChanged(app, event)
            app.numberOfRegions = app.AreastoExtractKnob.Value;         % set the number of the regions
        end

        % Value changed function: AreastoExtractDropDown
        function AreastoExtractDropDownValueChanged(app, event)
            stringNumber = app.AreastoExtractDropDown.Value;            
            app.regionsToExtract = str2double(stringNumber);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SegmentationAnalyzeUIFigure = uifigure('Visible', 'off');
            app.SegmentationAnalyzeUIFigure.Name = 'Segmentation Analyze';
            app.LoadImageMenu = uimenu(app.SegmentationAnalyzeUIFigure);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.AnalyzeMenu = uimenu(app.SegmentationAnalyzeUIFigure);
            app.AnalyzeMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalyzeImageButtonPushed, true);
            app.AnalyzeMenu.Enable = 'off';
            app.AnalyzeMenu.Text = 'Analyze';
            app.Menu = uimenu(app.SegmentationAnalyzeUIFigure);
            app.LoadImageButton = uibutton(app.SegmentationAnalyzeUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.FilePathEditFieldLabel = uilabel(app.SegmentationAnalyzeUIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.SegmentationAnalyzeUIFigure, 'text');
            app.FilePathEditField.Editable = 'off';
            app.AnalyzeImageButton = uibutton(app.SegmentationAnalyzeUIFigure, 'push');
            app.AnalyzeImageButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeImageButtonPushed, true);
            app.AnalyzeImageButton.Enable = 'off';
            app.AnalyzeImageButton.Text = 'Analyze Image';
            app.PixelAreaEditFieldLabel = uilabel(app.SegmentationAnalyzeUIFigure);
            app.PixelAreaEditFieldLabel.Text = 'Pixel Area';
            app.PixelAreaEditField = uieditfield(app.SegmentationAnalyzeUIFigure, 'numeric');
            app.PixelAreaEditField.Editable = 'off';
            app.FilterThresholdLabel = uilabel(app.SegmentationAnalyzeUIFigure);
            app.FilterThresholdLabel.Text = 'Filter Threshold';
            app.FilterThresholdSlider = uislider(app.SegmentationAnalyzeUIFigure);
            app.FilterThresholdSlider.Limits = [70 200];
            app.FilterThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @FilterThresholdSliderValueChanged, true);
            app.FilterThresholdSlider.Value = 70;
            app.AreastoExtractDropDownLabel = uilabel(app.SegmentationAnalyzeUIFigure);
            app.AreastoExtractDropDownLabel.Text = 'Areas to Extract';
            app.AreastoExtractDropDown = uidropdown(app.SegmentationAnalyzeUIFigure);
            app.AreastoExtractDropDown.Items = {'1', '2', '3', '4'};
            app.AreastoExtractDropDown.ItemsData = {'1', '2', '3', '4'};
            app.AreastoExtractDropDown.ValueChangedFcn = createCallbackFcn(app, @AreastoExtractDropDownValueChanged, true);
            app.AreastoExtractDropDown.Value = '1';
            app.SetthevalueyouwantLabel = uilabel(app.SegmentationAnalyzeUIFigure);
            app.SetthevalueyouwantLabel.Text = 'Set the value you want!';
            app.UIAxes = uiaxes(app.SegmentationAnalyzeUIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [];
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.SegmentationAnalyzeUIFigure);
            title(app.UIAxes2, 'Analyzed Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.AxesHistogram = uiaxes(app.SegmentationAnalyzeUIFigure);
            title(app.AxesHistogram, 'Histogram')
            xlabel(app.AxesHistogram, 'Intensity')
            ylabel(app.AxesHistogram, '# of Occurrences')
            % Show the figure after all components are created
            app.SegmentationAnalyzeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SegmentationAnalyzeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SegmentationAnalyzeUIFigure)
        end
    end
end