classdef gui_classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19DetectionApplicationUIFigure  matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        LoadImageMenu              matlab.ui.container.Menu
        DetectionMenu              matlab.ui.container.Menu
        GuideMenu_2                matlab.ui.container.Menu
        MedicineadviceMenu         matlab.ui.container.Menu
        EnhancmentTechniquesMenu   matlab.ui.container.Menu
        OriginalMenu               matlab.ui.container.Menu
        HisteqMenu                 matlab.ui.container.Menu
        CLAHEMenu                  matlab.ui.container.Menu
        ComplementMenu             matlab.ui.container.Menu
        GammaMenu                  matlab.ui.container.Menu
        BCETMenu                   matlab.ui.container.Menu
        AllEnhancmentMenu          matlab.ui.container.Menu
        HistogramsMenu             matlab.ui.container.Menu
        HisteqhistgramMenu         matlab.ui.container.Menu
        CLACHEhistogramMenu        matlab.ui.container.Menu
        GammaHistogramMenu         matlab.ui.container.Menu
        ComplementhistogramMenu    matlab.ui.container.Menu
        BCEThistogramMenu          matlab.ui.container.Menu
        CAMMenu                    matlab.ui.container.Menu
        InfromationMenu            matlab.ui.container.Menu
        HelpMenu                   matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        PAECMonogram               matlab.ui.control.Image
        COVID19DetectionAnalysisusingCTLungsImagesLabel  matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        LoadImageButton            matlab.ui.control.Button
        OriginalImage              matlab.ui.control.Image
        HisteqImageButton          matlab.ui.control.Button
        CLAHEButton                matlab.ui.control.Button
        GammaButton                matlab.ui.control.Button
        ComplementButton           matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        CAMButton_3                matlab.ui.control.Button
        DetectionButton            matlab.ui.control.Button
        AccuracyButton_2           matlab.ui.control.Button
        ResizeImageButton          matlab.ui.control.Button
        EnhancmentTechniquesLabel  matlab.ui.control.Label
        BCETButton                 matlab.ui.control.Button
        OriginalImageButton        matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        DetectionLabel             matlab.ui.control.Label
        DetectionLabelTitle        matlab.ui.control.Label
        PAICMonogram               matlab.ui.control.Image
        Label                      matlab.ui.control.Label
        PAECMonogram_2             matlab.ui.control.Image
        mylamp                     matlab.ui.control.Lamp
        edit1                      matlab.ui.control.EditField
        EditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
         original_image          % to store original image
         resized_image           % image after dwt preprocessing
         classification_model    %   
         
         %Variables for different filters
         output_image
         chache_image
         gamma_image
         complement_image
         bcet_image
         histeqimage
         
         %Path
         path = 'D:\Study_University_Borys_Bilkevych_4_course_1_semestr\Diplom_work\October 11 2021\Staging-COVID-19-Detection-System-using-Chest-X-Ray-Images-master'
         
         %Detection result
         A
    
        end
    
    methods (Access = private)
        
        function HisteqImage(app, event) 
            ResizeImage(app, event)
            app.histeqimage=histeq(app.resized_image);            
        
            Original_Image = app.resized_image;

            numofpixels = size(Original_Image,1) * size(Original_Image,2);
            
            app.output_image = uint8(zeros(size(Original_Image,1), size(Original_Image,2)));
            frequency = zeros(256,1); 
            prob_f = zeros(256,1);
            prob_c = zeros(256,1);
            accumulator = zeros(256,1);
            output = zeros(256,1);
            
            %frequency counts the occurrence of each pixel value.
            %The probability of each occurrence is calculated by prob_f.
            for i=1:size(Original_Image,1)
                for j=1:size(Original_Image,2)
                    value = Original_Image(i,j);
                    frequency(value+1) = frequency(value+1)+1;
                    prob_f(value+1) = frequency(value+1)/numofpixels;
                end
            end
            
            sum = 0;
            no_bins = 255;
            
            %The accumulatorulative distribution probability is calculated.
            for i=1:size(prob_f)
               sum = sum+frequency(i);
               accumulator(i) = sum;
               prob_c(i) = accumulator(i) / numofpixels;
               output(i) = round(prob_c(i) * no_bins);
            end
            
            for i=1:size(Original_Image,1)
                for j=1:size(Original_Image,2)
                        app.output_image(i,j) = output(Original_Image(i,j) + 1);
                end
            end
        end
        
        function GammaImage(app, event)
            ResizeImage(app, event)
            app.gamma_image=imadjust(app.resized_image,[0 0.2],[0.5 1]);
        end
        
        function BCETImage(app, event)
            ResizeImage(app, event)
            I = app.resized_image;
            
            x    = double(I); % INPUT IMAGE
            Lmin = min(x(:)); % MINIMUM OF INPUT IMAGE
            Lmax = max(x(:)); % MAXIMUM OF INPUT IMAGE
            Lmean = mean(x(:)); %MEAN OF INPUT IMAGE
            LMssum = mean(x(:).^2); %MEAN SQUARE SUM OF INPUT IMAGE
            
            Gmin = 0; %MINIMUM OF OUTPUT IMAGE
            Gmax = 255; %MAXIMUM OF OUTPUT IMAGE
            Gmean = 110; %MEAN OF OUTPUT IMAGE
            
            bnum = Lmax.^2*(Gmean-Gmin) - LMssum*(Gmax-Gmin) + Lmin.^2*(Gmax-Gmean);
            bden = 2*(Lmax*(Gmean-Gmin)-Lmean*(Gmax-Gmin)+Lmin*(Gmax-Gmean));
            
            b = bnum/bden;
            a = (Gmax-Gmin)/((Lmax-Lmin)*(Lmax+Lmin-2*b));
            c = Gmin - a*(Lmin-b).^2;
            app.bcet_image = a*(x-b).^2+c; %PARABOLIC FUNCTION
            app.bcet_image = uint8(app.bcet_image);
            
        end
        
        function CLACHEImage(app, event)
            ResizeImage(app, event)
            clache_image=app.resized_image; 
            app.chache_image = adapthisteq(rgb2gray(clache_image),'clipLimit',0.02,'Distribution','rayleigh');
        end
        
        function ComplementImage(app, event)
            ResizeImage(app, event)
            app.complement_image=imcomplement(app.resized_image);
        end
        
        function ResizeImage(app, ~)
            app.resized_image = imresize(app.original_image, [224, 224]);
            
            channels = size(size(app.resized_image));
            if channels(2) == 2
                app.resized_image = cat(3, app.resized_image, app.resized_image, app.resized_image);
            end
        end
        
        function setAccuracyValue(app, idx)
            app.EditField.Value=idx;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = uifigure; d = uiprogressdlg(fig,'Title','Loading Classification Model','Indeterminate','on'); drawnow;
            load('../proposed-CB-STM-RENet-model/Proposed_CB_STM_RENet.mat','Proposed_CB_STM_RENet');
            app.classification_model = Proposed_CB_STM_RENet;
            delete(d); delete(fig);
            d = datetime('today');
            str = string(d);
            app.edit1.Value=str;
        end

        % Callback function: LoadImageButton, LoadImageMenu
        function LoadImageButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.jpg';'*.png'},'Select Image File',strcat(app.path,'\Covid-X-ray-images'));
            if class(filename) == "double"
                return;
            end
            
            app.original_image = imread([filepath, filename]);
            
            channels = size(size(app.original_image));
            if channels(2) == 2
                app.original_image = cat(3, app.original_image, app.original_image, app.original_image);
            end
            
            
            app.OriginalImage.ImageSource = app.original_image;
            
            app.DetectionLabelTitle.Text = "";
            
            %Enable all the buttons on the main page
            app.DetectionButton.Enable = 1;
            app.OriginalImage.Enable = 1;
            app.HisteqImageButton.Enable = 1;
            app.CLAHEButton.Enable = 1;
            app.GammaButton.Enable = 1;
            app.ComplementButton.Enable = 1;
            app.Button.Enable = 1;
            app.CAMButton_3.Enable = 1;
            app.DetectionButton.Enable = 1;
            app.ResizeImageButton.Enable = 1;
            app.EnhancmentTechniquesLabel.Enable = 1;
            app.BCETButton.Enable = 1;
            app.OriginalImageButton.Enable = 1;
            
            %Enable all the button on the menu
            app.DetectionMenu.Enable = 1;
            app.HisteqMenu.Enable = 1;
            app.BCETMenu.Enable = 1;
            app.OriginalMenu.Enable = 1;
            app.AllEnhancmentMenu.Enable = 1;
            app.GammaMenu.Enable = 1;
            app.CLAHEMenu.Enable = 1;
            app.ComplementMenu.Enable = 1;

            app.DetectionLabel.Text = "";
            app.DetectionLabel.FontColor = [0 0 0];
            
            %Run image functions
            HisteqImage(app, event);
            GammaImage(app, event);
            BCETImage(app, event);
            CLACHEImage(app, event);
            ComplementImage(app, event);
        end

        % Button pushed function: ResizeImageButton
        function ResizeImageButtonPushed(app, event)
            app.resized_image = imresize(app.original_image, [224, 224]);
            
            channels = size(size(app.resized_image));
            if channels(2) == 2
                app.resized_image = cat(3, app.resized_image, app.resized_image, app.resized_image);
            end
            
            app.OriginalImage.ImageSource = app.resized_image;

        end

        % Callback function: OriginalImageButton, OriginalMenu
        function OriginalImageButtonPushed(app, event)
            ResizeImage(app, event)        
            Original_Image = app.resized_image;
            
            %ImShow the Original Image
            figure, imshow(Original_Image);
            title('Original Image'); 
        end

        % Callback function: HisteqImageButton, HisteqMenu
        function HisteqImageButtonPushed(app, event)
            app.OriginalImage.ImageSource = app.histeqimage;

            %Imshow the Image after Histogram equalization
            figure
            imshowpair(app.resized_image,app.output_image,'montage');
            title('Left: Original Image and Right: Histeq Enhanced Image');
        end

        % Callback function: GammaButton, GammaMenu
        function GammaButtonPushed(app, event)
            app.OriginalImage.ImageSource = app.gamma_image;

            %ImShow the Gamma Image
            figure
            imshowpair(app.resized_image,app.gamma_image,'montage');
            title('Left: Original Image Right: Gamma Enhanced Image');
        end

        % Callback function: BCETButton, BCETMenu
        function BCETButtonPushed(app, event)
            app.OriginalImage.ImageSource = app.bcet_image;

            %ImShow the BCET Image
            figure
            imshowpair(app.resized_image,app.bcet_image,'montage');
            title('Original Image (left) and BCET Enhanced Image (right)');
        end

        % Callback function: CLAHEButton, CLAHEMenu
        function CLAHEButtonPushed(app, event)
            app.OriginalImage.ImageSource = app.original_image;

            %ImShow the CLACHE Image
            figure
            imshowpair(app.resized_image,app.chache_image,'montage');
            title('Left: Original Image and Right: CLACHE Enhanced Image');
        end

        % Callback function: ComplementButton, ComplementMenu
        function ComplementButtonPushed(app, event)
            app.OriginalImage.ImageSource = app.complement_image;
            
            %ImShow the CLACHE Image
            figure
            imshowpair(app.resized_image,app.complement_image,'montage');
            title('Left: Original Image and Right: Complement Enhanced Image');
        end

        % Menu selected function: HisteqhistgramMenu
        function HistogramofHisteqImageMenuSelected(app, event)
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.output_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Menu selected function: CLACHEhistogramMenu
        function HistogramofCLAHEImageMenuSelected(app, event)
            ResizeImage(app, event)
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.chache_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Menu selected function: GammaHistogramMenu
        function HistogramofGammaImageMenuSelected(app, event)
            ResizeImage(app, event)
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.gamma_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Menu selected function: ComplementhistogramMenu
        function HistogramofComplementImageMenuSelected(app, event)
            ResizeImage(app, event)
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.complement_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Menu selected function: BCEThistogramMenu
        function HistogramofBCETImageMenuSelected(app, event)
            ResizeImage(app, event)
            
            Input_hist = imhist(app.original_image(:));
            Output_hist = imhist(app.bcet_image(:));

            figure,stem([0:255],Input_hist);
            hold on;stem([0:255],Output_hist);
            legend INPUT OUTPUT
        end

        % Menu selected function: AllEnhancmentMenu
        function AllEnhancmentMenuSelected(app, event)
            
            subplot(2,3,1); imshow(app.resized_image);
            title(sprintf('Original image'));
            
            subplot(2,3,2); imshow(app.output_image);
            title(sprintf('Histeq image'));
            
            subplot(2,3,3); imshow(app.chache_image);
            title(sprintf('Clache image'));
            
            subplot(2,3,4); imshow(app.gamma_image);
            title(sprintf('Gamma image'));
            
            subplot(2,3,5); imshow(app.complement_image);
            title(sprintf('Complement image'));
            
            subplot(2,3,6); imshow(app.bcet_image);
            title(sprintf('Bcet image'));
        end

        % Callback function: CAMButton_3, CAMMenu
        function CAMButton_3Pushed(app, event)
            ResizeImage(app, event)
            imSize = size(im);
            CAM = imresize(CAM,imSize(1:2));
            
            minimum = min(CAM(:));
            maximum = max(CAM(:));
            N = (CAM-minimum)/(maximum-minimum);
            CAM=N;
            
            CAM(CAM<0.2) = 0;
            cmap = jet(255).*linspace(0,1,255)';
            CAM = ind2rgb(uint8(CAM*255),cmap)*255;
            
            combinedImage = double(rgb2gray(im))/2 + CAM;
            
            minimum = min(combinedImage(:));
            maximum = max(combinedImage(:));
            NN = (combinedImage-minimum)/(maximum-minimum);
            
            combinedImage = NN*255;
            
            imshow(uint8(combinedImage));
        end

        % Button pushed function: AccuracyButton_2
        function AccuracyButton_2Pushed(app, event)
            % Images Datapath
            datapath='D:\Study_University_Borys_Bilkevych_4_course_1_semestr\Diplom_work\October 11 2021\Staging-COVID-19-Detection-System-using-Chest-X-Ray-Images-master\Datasheet-for-accuracy-checking';
            count=0;
            
            % Image Datastore
            imds=imageDatastore(datapath, ...
                'IncludeSubfolders',true, ...
                'LabelSource','foldernames');
            
            % Number of Images
            num_images=length(imds.Labels);
            
            % Visualize random images
            perm=randperm(num_images,10);
            
            for idx=1:length(perm)
                setAccuracyValue(app,idx);
                resize_image = imresize(perm(idx), [224, 224]);
                
                channels = size(size(resize_image));
                if channels(2) == 2
                    resize_image = cat(3, resize_image, resize_image, resize_image);
                end
                
                %Detection 
                [predicted_labels,posterior] = classify(app.classification_model,resize_image);
                
                fprintf("posterior: %f\n", posterior);
                fprintf("predicted_labels: %f\n", predicted_labels);           
                res = categorical(predicted_labels);
                fprintf("Classification Label: %s\n", res)
                fprintf("Number of images: %f\n", num_images)
                sprintf('%s',imds.Labels(perm(idx)))

                if(imds.Labels(perm(idx))==res)
                    count=count+1;
                end
                pause(0.05);
                %set(app.EditField,)
%                 subplot(2,3,idx);
%                 imshow(imread(imds.Files{perm(idx)}));
%                 title(sprintf('%s',imds.Labels(perm(idx))))
%                 
            end
%             
%             
%             
%             
%             
%             % Number of Images
%             num_images=length(imds.Labels);
%             perm=num_images;
%             % Visualize random images
%             %perm=randperm(num_images,1);
%             %str = strcat(datapath,'\COVID-19\COVID-20')
              fprintf('%f',count)
% 
%             
%             for idx=1:length(perm)
%                 %Resize Image
%                 resize_image = imresize(perm, [224, 224]);
%                 
%                 channels = size(size(resize_image));
%                 if channels(2) == 2
%                     resize_image = cat(3, resize_image, resize_image, resize_image);
%                 end
%                 
%                 %Detection 
%                 [predicted_labels,posterior] = classify(app.classification_model,resize_image);
%                 
%                 fprintf("posterior: %f\n", posterior);
%                 fprintf("predicted_labels: %f\n", predicted_labels);           
%                 res = categorical(predicted_labels);
%                 fprintf("Classification Label: %s\n", res)
%                 fprintf("Number of images: %f\n", num_images)
%                 %sprintf('%s',imds.Labels(perm(idx)))
%             end
            
%             figure;
%             for idx=1:length(perm)
%                 subplot(1,2,idx);
%                 imshow(imread(imds.Files{perm(idx)}));
%                 title(sprintf('%f',perm(idx)))
%               %  if res=="NonCOVID-19"
%                         title(sprintf('%s',res))
%                 %end
% 
%             end
        end

        % Callback function: DetectionButton, DetectionMenu
        function DetectionButtonPushed(app, event)
            ResizeImage(app, event)
            app.MedicineadviceMenu.Enable = 1;
            
            fig = uifigure; d = uiprogressdlg(fig,'Title','Running Classification Model','Indeterminate','on'); drawnow;
            [predicted_labels,posterior] = classify(app.classification_model,app.resized_image);
            delete(d); delete(fig);
            
            fprintf("posterior: %f\n", posterior);
            fprintf("predicted_labels: %f\n", predicted_labels);
            app.A = categorical(predicted_labels);
            
            if(app.A == "COVID-19")
                str = """You are COVID-19 Infected""";
                str = str + newline + """Follow the actions from medicine advice""";
                app.DetectionLabelTitle.Text = "Attention";
                app.DetectionLabel.Text = str;
                app.DetectionLabelTitle.FontColor = [1 0 0];
                app.DetectionLabel.FontColor = [1 0 0];
                app.mylamp.Color = [1.00,0.00,0.00];
            else
                str = """You are Non-COVID-19 Infected""";
                str = str + newline + """You can follow the actions from medicine advice""";
                app.DetectionLabelTitle.Text = "Information";
                app.DetectionLabel.Text = str;
                app.DetectionLabelTitle.FontColor = [0 1 0];
                app.DetectionLabel.FontColor = [0 1 0];
                app.mylamp.Color = [0.00,1.00,0.00];
            end
        end

        % Menu selected function: GuideMenu_2
        function GuideMenu_2Selected(app, event)
            uiwait(msgbox({'Welcome to Guide Page for the Covid-19 Detection App'; ...
                '1. Before starting the program, please check if you have a model file'; ...
                '2. After starting the programm Load the X-ray image from the datasheet'; ...
                '3. Use the Detection button for the covid-19 detect'; ...
                '4. You can use the different enhancment techniques for image modifying'; ...
                '5. If you are infected or not. You can check the current medicine advice'; ...
                '6. Use CAM for looking at the infected part of lungs'; ...
                '7. Also you can take a look at interactive segmentation fo the lungs'; ...
                '';'Good Luck with using Detection Tool!'},'Guide for Detection tool',"help",'modal'));
        end

        % Menu selected function: InfromationMenu
        function InfromationMenuSelected(app, event)
            uiwait(msgbox({'Welcome to Covid-19 Detection Application'; ...
                'You can check the condition of your lungs and look at the Covid-19 situation.'; ...
                'I wish you good luck and health. Take care about yourself and people around you'; ...
                'Use mask outdoors and make a vaccination'},'Information',"help",'modal'));
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            uiwait(msgbox({'Covid-19 Detection Tool'; ...
                '1. Load Image - choose the X-ray Image from your computer (*.png, *.jpg)'; ...
                '2. Resize Image - change the image size to [224, 224] for the correct detection of Covid-19'; ...
                '3. Enhancment Techniques - kind of improving the image'; ...
                '   3.1 Original Image - enter the original image for comparing'; ...
                '   3.2 Histeq Image - perform histogram equalization'; ...
                '   3.3 CLACHE Image - Contrast-limited adaptive histogram equalization'; ...
                '   3.4 Gamma Image - execute the gamma function'; ...
                '   3.5 Complement Image - In the complement of a binary image, zeros become ones and ones become zeros. Black and white are reversed.'; ...
                '   3.6 BCET Image - Balance Contrast Enchancment'; ...
                '4. CAM (Class Activation Mapping) - return the infected parts of lungs'; ...
                '5. Accuracy - return the value of the althoritm accuracy'; ...
                '6. Detection - infected/no infected'; ...
                '7. Histograms - a graphical representation that organizes a group of data points into user-specified ranges'; ...
                '8. Medicine Advice - return the advice regarding your result of Covid-19 Detection'; ...
                '9. Guide - information page, how to use the Covid-19 Detection App'; ...
                '10. All Enhancment - return the plot with all the enhancment techniques'; ...
                '12. Segmentation - interactive segmentation of the lungs on image'},'Help',"help",'modal'));
        end

        % Menu selected function: MedicineadviceMenu
        function MedicineadviceMenuSelected(app, event)
            if(app.A == "COVID-19")
                uiwait(msgbox({'1. Stay at home and call to your local doctor for help'; '2. After consultation. Follow all instructions'; ...
                    '3. Spend more time outdoors, always ventilate the house. Also drink and eat more vitamins';'4. If it gets worse, be hospitalized immediately'; ...
                    '5. Make a vaccination'},'Medicine advice for ill people',"error",'modal'));
            else
                uiwait(msgbox({'1. Be careful outdoor'; '2. Eat and drink more vitamins'; ...
                    '3. Try to avoid the conversation with ill people';'4. Make a vaccination'},'Medicine advice for helthy people',"help",'modal'));
            end
        end

        % Callback function
        function SegmentationMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19DetectionApplicationUIFigure = uifigure('Visible', 'off');
            app.Covid19DetectionApplicationUIFigure.Name = 'Covid-19 Detection Application';
            app.Covid19DetectionApplicationUIFigure.Icon = 'good.jpg';
            app.Menu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.Menu.Tooltip = {'Program menu'};
            app.Menu.Text = 'Menu';
            app.LoadImageMenu = uimenu(app.Menu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.DetectionMenu = uimenu(app.Menu);
            app.DetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionMenu.Enable = 'off';
            app.DetectionMenu.Text = 'Detection';
            app.GuideMenu_2 = uimenu(app.Menu);
            app.GuideMenu_2.MenuSelectedFcn = createCallbackFcn(app, @GuideMenu_2Selected, true);
            app.GuideMenu_2.Tooltip = {'The small intro how to use the program'};
            app.GuideMenu_2.Text = 'Guide';
            app.MedicineadviceMenu = uimenu(app.Menu);
            app.MedicineadviceMenu.MenuSelectedFcn = createCallbackFcn(app, @MedicineadviceMenuSelected, true);
            app.MedicineadviceMenu.Tooltip = {'Get a medicine advice regarding your current status of Covid-19'};
            app.MedicineadviceMenu.Enable = 'off';
            app.MedicineadviceMenu.Text = 'Medicine advice';
            app.EnhancmentTechniquesMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.EnhancmentTechniquesMenu.Tooltip = {'Choose the techniques which you want to see'};
            app.EnhancmentTechniquesMenu.Text = 'Enhancment Techniques';
            app.OriginalMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.OriginalMenu.MenuSelectedFcn = createCallbackFcn(app, @OriginalImageButtonPushed, true);
            app.OriginalMenu.Enable = 'off';
            app.OriginalMenu.Text = 'Original';
            app.HisteqMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.HisteqMenu.MenuSelectedFcn = createCallbackFcn(app, @HisteqImageButtonPushed, true);
            app.HisteqMenu.Enable = 'off';
            app.HisteqMenu.Text = 'Histeq';
            app.CLAHEMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.CLAHEMenu.MenuSelectedFcn = createCallbackFcn(app, @CLAHEButtonPushed, true);
            app.CLAHEMenu.Enable = 'off';
            app.CLAHEMenu.Text = 'CLAHE';
            app.ComplementMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.ComplementMenu.MenuSelectedFcn = createCallbackFcn(app, @ComplementButtonPushed, true);
            app.ComplementMenu.Enable = 'off';
            app.ComplementMenu.Text = 'Complement';
            app.GammaMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.GammaMenu.MenuSelectedFcn = createCallbackFcn(app, @GammaButtonPushed, true);
            app.GammaMenu.Enable = 'off';
            app.GammaMenu.Text = 'Gamma';
            app.BCETMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.BCETMenu.MenuSelectedFcn = createCallbackFcn(app, @BCETButtonPushed, true);
            app.BCETMenu.Enable = 'off';
            app.BCETMenu.Text = 'BCET';
            app.AllEnhancmentMenu = uimenu(app.EnhancmentTechniquesMenu);
            app.AllEnhancmentMenu.MenuSelectedFcn = createCallbackFcn(app, @AllEnhancmentMenuSelected, true);
            app.AllEnhancmentMenu.Enable = 'off';
            app.AllEnhancmentMenu.Text = 'All Enhancment';
            app.HistogramsMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.HistogramsMenu.Tooltip = {'Output the histogram for all of Enchancment Techniques'};
            app.HistogramsMenu.Text = 'Histograms';
            app.HisteqhistgramMenu = uimenu(app.HistogramsMenu);
            app.HisteqhistgramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofHisteqImageMenuSelected, true);
            app.HisteqhistgramMenu.Text = 'Histeq histgram';
            app.CLACHEhistogramMenu = uimenu(app.HistogramsMenu);
            app.CLACHEhistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofCLAHEImageMenuSelected, true);
            app.CLACHEhistogramMenu.Text = 'CLACHE histogram';
            app.GammaHistogramMenu = uimenu(app.HistogramsMenu);
            app.GammaHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofGammaImageMenuSelected, true);
            app.GammaHistogramMenu.Text = 'Gamma Histogram';
            app.ComplementhistogramMenu = uimenu(app.HistogramsMenu);
            app.ComplementhistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofComplementImageMenuSelected, true);
            app.ComplementhistogramMenu.Text = 'Complement histogram';
            app.BCEThistogramMenu = uimenu(app.HistogramsMenu);
            app.BCEThistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramofBCETImageMenuSelected, true);
            app.BCEThistogramMenu.Text = 'BCET histogram';
            app.CAMMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.CAMMenu.MenuSelectedFcn = createCallbackFcn(app, @CAMButton_3Pushed, true);
            app.CAMMenu.Tooltip = {'Use a CAM for detecting the infected part'};
            app.CAMMenu.Text = 'CAM';
            app.InfromationMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.InfromationMenu.MenuSelectedFcn = createCallbackFcn(app, @InfromationMenuSelected, true);
            app.InfromationMenu.Tooltip = {'Information about program'};
            app.InfromationMenu.Text = 'Infromation';
            app.HelpMenu = uimenu(app.Covid19DetectionApplicationUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Tooltip = {'A full description of the created features'};
            app.HelpMenu.Text = 'Help';
            app.GridLayout = uigridlayout(app.Covid19DetectionApplicationUIFigure);
            app.PAECMonogram = uiimage(app.GridLayout);
            app.PAECMonogram.Tooltip = {'Logo og the Faculty'};
            app.PAECMonogram.ImageSource = 'frecs_en.png';
            app.COVID19DetectionAnalysisusingCTLungsImagesLabel = uilabel(app.GridLayout);
            app.COVID19DetectionAnalysisusingCTLungsImagesLabel.Text = {'COVID-19 Detection'; 'Analysis using CT Lungs Images'};
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.LoadImageButton = uibutton(app.GridLayout2, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Tooltip = {'Load the image from your Datasheet'};
            app.LoadImageButton.Text = 'Load Image';
            app.OriginalImage = uiimage(app.GridLayout2);
            app.OriginalImage.Enable = 'off';
            app.OriginalImage.Tooltip = {'Image for X-ray Lungs image'};
            app.OriginalImage.ImageSource = 'good.jpg';
            app.HisteqImageButton = uibutton(app.GridLayout2, 'push');
            app.HisteqImageButton.ButtonPushedFcn = createCallbackFcn(app, @HisteqImageButtonPushed, true);
            app.HisteqImageButton.Enable = 'off';
            app.HisteqImageButton.Tooltip = {'Use a Histeq techniques for your image'};
            app.HisteqImageButton.Text = 'Histeq Image';
            app.CLAHEButton = uibutton(app.GridLayout2, 'push');
            app.CLAHEButton.ButtonPushedFcn = createCallbackFcn(app, @CLAHEButtonPushed, true);
            app.CLAHEButton.Enable = 'off';
            app.CLAHEButton.Tooltip = {'Use a CLACHE techniques for your image'};
            app.CLAHEButton.Text = 'CLAHE';
            app.GammaButton = uibutton(app.GridLayout2, 'push');
            app.GammaButton.ButtonPushedFcn = createCallbackFcn(app, @GammaButtonPushed, true);
            app.GammaButton.Enable = 'off';
            app.GammaButton.Tooltip = {'Use a Gamma techniques for your image'};
            app.GammaButton.Text = 'Gamma';
            app.ComplementButton = uibutton(app.GridLayout2, 'push');
            app.ComplementButton.ButtonPushedFcn = createCallbackFcn(app, @ComplementButtonPushed, true);
            app.ComplementButton.Enable = 'off';
            app.ComplementButton.Tooltip = {'Use a Complement techniques for your image'};
            app.ComplementButton.Text = 'Complement';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '???';
            app.CAMButton_3 = uibutton(app.GridLayout2, 'push');
            app.CAMButton_3.ButtonPushedFcn = createCallbackFcn(app, @CAMButton_3Pushed, true);
            app.CAMButton_3.Enable = 'off';
            app.CAMButton_3.Tooltip = {'Use a CAM for detecting the infected part'};
            app.CAMButton_3.Text = 'CAM';
            app.DetectionButton = uibutton(app.GridLayout2, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Enable = 'off';
            app.DetectionButton.Tooltip = {'Detect the Covid-19 through X-ray Image'};
            app.DetectionButton.Text = 'Detection';
            app.AccuracyButton_2 = uibutton(app.GridLayout2, 'push');
            app.AccuracyButton_2.ButtonPushedFcn = createCallbackFcn(app, @AccuracyButton_2Pushed, true);
            app.AccuracyButton_2.Tooltip = {'Count the accuracy for a big training datasheet'};
            app.AccuracyButton_2.Text = 'Accuracy';
            app.ResizeImageButton = uibutton(app.GridLayout2, 'push');
            app.ResizeImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeImageButtonPushed, true);
            app.ResizeImageButton.Enable = 'off';
            app.ResizeImageButton.Tooltip = {'Resize the image to [224,224]'};
            app.ResizeImageButton.Text = 'Resize Image';
            app.EnhancmentTechniquesLabel = uilabel(app.GridLayout2);
            app.EnhancmentTechniquesLabel.Enable = 'off';
            app.EnhancmentTechniquesLabel.Text = 'Enhancment Techniques';
            app.BCETButton = uibutton(app.GridLayout2, 'push');
            app.BCETButton.ButtonPushedFcn = createCallbackFcn(app, @BCETButtonPushed, true);
            app.BCETButton.Enable = 'off';
            app.BCETButton.Tooltip = {'Use a BCET techniques for your image'};
            app.BCETButton.Text = 'BCET';
            app.OriginalImageButton = uibutton(app.GridLayout2, 'push');
            app.OriginalImageButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalImageButtonPushed, true);
            app.OriginalImageButton.Enable = 'off';
            app.OriginalImageButton.Tooltip = {'Output the Original version of image to another UI'};
            app.OriginalImageButton.Text = 'Original Image';
            app.Panel_2 = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel_2);
            app.GridLayout4.Tooltip = {'Field for status of Covid-19'};
            app.DetectionLabel = uilabel(app.GridLayout4);
            app.DetectionLabel.Tag = 'DetectionLabel';
            app.DetectionLabel.Text = '';
            app.DetectionLabelTitle = uilabel(app.GridLayout4);
            app.DetectionLabelTitle.Text = '';
            app.PAICMonogram = uiimage(app.GridLayout);
            app.PAICMonogram.ImageSource = 'paic-logo.jpg';
            app.Label = uilabel(app.GridLayout);
            app.Label.Tooltip = {'Diploma work Bilkevych Borys (DL) 2021'};
            app.Label.Text = 'Diploma work Bilkevych Borys 2021';
            app.PAECMonogram_2 = uiimage(app.GridLayout);
            app.PAECMonogram_2.Tooltip = {'Logo of the Application'};
            app.PAECMonogram_2.ImageSource = 'good.jpg';
            app.mylamp = uilamp(app.GridLayout);
            app.mylamp.Tooltip = {'Show the status of Covid in lungs image'; '1. Green no Covid'; '2. Red Covid infected'};
            app.edit1 = uieditfield(app.GridLayout, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Editable = 'off';
            app.edit1.Tooltip = {'Current Date'};
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField.Tooltip = {'Show the current value of images which was already scanned for accuracy'};
            % Show the figure after all components are created
            app.Covid19DetectionApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19DetectionApplicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19DetectionApplicationUIFigure)
        end
    end
end